classdef clicktrace4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        cmaxEditField            matlab.ui.control.NumericEditField
        cmaxEditFieldLabel       matlab.ui.control.Label
        cminEditField            matlab.ui.control.NumericEditField
        cminEditFieldLabel       matlab.ui.control.Label
        save_dsetButton          matlab.ui.control.Button
        savepathEditField        matlab.ui.control.EditField
        savepathEditFieldLabel   matlab.ui.control.Label
        removemeanmeanCheckBox   matlab.ui.control.CheckBox
        viewbkgcompButton        matlab.ui.control.Button
        plotroiCheckBox          matlab.ui.control.CheckBox
        meanButton               matlab.ui.control.StateButton
        compButton               matlab.ui.control.StateButton
        bkgcompSpinner           matlab.ui.control.Spinner
        bkgcompSpinnerLabel      matlab.ui.control.Label
        removebkgcompButton      matlab.ui.control.Button
        compnumplotSpinner       matlab.ui.control.Spinner
        compnumplotSpinnerLabel  matlab.ui.control.Label
        componentsDropDown       matlab.ui.control.DropDown
        componentsDropDownLabel  matlab.ui.control.Label
        im_sourceDropDown        matlab.ui.control.DropDown
        im_sourceDropDownLabel   matlab.ui.control.Label
        RemovecellButton         matlab.ui.control.Button
        AddcellButton            matlab.ui.control.Button
        LoaddsetButton           matlab.ui.control.Button
        moviepathEditField       matlab.ui.control.EditField
        moviepathEditFieldLabel  matlab.ui.control.Label
        UIAxes_trace             matlab.ui.control.UIAxes
        UIAxes_components        matlab.ui.control.UIAxes
        UIAxes_data              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        data; % Description
        plots;
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('C:\Users\ys2605\Desktop\stuff\clicktrace4');
            addpath('C:\Users\ys2605\Desktop\stuff\clicktrace4\functions');
            f_ct_initialize(app);
        end

        % Button pushed function: LoaddsetButton
        function LoaddsetButtonPushed(app, event)
            f_ct_load_dset(app);
        end

        % Button pushed function: AddcellButton
        function AddcellButtonPushed(app, event)
            f_ct_add_cell(app);
        end

        % Button pushed function: RemovecellButton
        function RemovecellButtonPushed(app, event)
            f_ct_remove_cell(app);
        end

        % Value changed function: im_sourceDropDown
        function im_sourceDropDownValueChanged(app, event)
            f_ct_update_im_source(app);
        end

        % Button pushed function: removebkgcompButton
        function removebkgcompButtonPushed(app, event)
            f_ct_update_bkg(app);
        end

        % Value changed function: compButton
        function compButtonValueChanged(app, event)
            f_ct_update_trace_plot(app);
        end

        % Value changed function: meanButton
        function meanButtonValueChanged(app, event)
            f_ct_update_trace_plot(app);
        end

        % Button pushed function: viewbkgcompButton
        function viewbkgcompButtonPushed(app, event)
            f_ct_view_bkg(app);
        end

        % Button pushed function: save_dsetButton
        function save_dsetButtonPushed(app, event)
            f_ct_save_dset(app);
        end

        % Value changed function: cminEditField
        function cminEditFieldValueChanged(app, event)
            f_ct_update_clim(app);
        end

        % Value changed function: cmaxEditField
        function cmaxEditFieldValueChanged(app, event)
            f_ct_update_clim(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_data = uiaxes(app.UIFigure);
            title(app.UIAxes_data, 'Data')
            xlabel(app.UIAxes_data, 'X')
            ylabel(app.UIAxes_data, 'Y')
            zlabel(app.UIAxes_data, 'Z')
            app.UIAxes_components = uiaxes(app.UIFigure);
            title(app.UIAxes_components, 'Components')
            xlabel(app.UIAxes_components, 'X')
            ylabel(app.UIAxes_components, 'Y')
            zlabel(app.UIAxes_components, 'Z')
            app.UIAxes_trace = uiaxes(app.UIFigure);
            title(app.UIAxes_trace, 'Trace')
            xlabel(app.UIAxes_trace, 'X')
            ylabel(app.UIAxes_trace, 'Y')
            zlabel(app.UIAxes_trace, 'Z')
            app.moviepathEditFieldLabel = uilabel(app.UIFigure);
            app.moviepathEditFieldLabel.Text = 'movie path';
            app.moviepathEditField = uieditfield(app.UIFigure, 'text');
            app.LoaddsetButton = uibutton(app.UIFigure, 'push');
            app.LoaddsetButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddsetButtonPushed, true);
            app.LoaddsetButton.Text = 'Load dset';
            app.AddcellButton = uibutton(app.UIFigure, 'push');
            app.AddcellButton.ButtonPushedFcn = createCallbackFcn(app, @AddcellButtonPushed, true);
            app.AddcellButton.Text = 'Add cell';
            app.RemovecellButton = uibutton(app.UIFigure, 'push');
            app.RemovecellButton.ButtonPushedFcn = createCallbackFcn(app, @RemovecellButtonPushed, true);
            app.RemovecellButton.Text = 'Remove cell';
            app.im_sourceDropDownLabel = uilabel(app.UIFigure);
            app.im_sourceDropDownLabel.Text = 'im_source';
            app.im_sourceDropDown = uidropdown(app.UIFigure);
            app.im_sourceDropDown.Items = {'corr', 'std'};
            app.im_sourceDropDown.ValueChangedFcn = createCallbackFcn(app, @im_sourceDropDownValueChanged, true);
            app.im_sourceDropDown.Value = 'corr';
            app.componentsDropDownLabel = uilabel(app.UIFigure);
            app.componentsDropDownLabel.Text = 'components';
            app.componentsDropDown = uidropdown(app.UIFigure);
            app.componentsDropDown.Items = {'comps', 'background'};
            app.componentsDropDown.Value = 'comps';
            app.compnumplotSpinnerLabel = uilabel(app.UIFigure);
            app.compnumplotSpinnerLabel.Text = 'comp num plot';
            app.compnumplotSpinner = uispinner(app.UIFigure);
            app.removebkgcompButton = uibutton(app.UIFigure, 'push');
            app.removebkgcompButton.ButtonPushedFcn = createCallbackFcn(app, @removebkgcompButtonPushed, true);
            app.removebkgcompButton.Text = 'remove bkg comp';
            app.bkgcompSpinnerLabel = uilabel(app.UIFigure);
            app.bkgcompSpinnerLabel.Text = 'bkg comp';
            app.bkgcompSpinner = uispinner(app.UIFigure);
            app.bkgcompSpinner.Limits = [0 Inf];
            app.bkgcompSpinner.RoundFractionalValues = 'on';
            app.compButton = uibutton(app.UIFigure, 'state');
            app.compButton.ValueChangedFcn = createCallbackFcn(app, @compButtonValueChanged, true);
            app.compButton.Text = 'comp';
            app.compButton.Value = true;
            app.meanButton = uibutton(app.UIFigure, 'state');
            app.meanButton.ValueChangedFcn = createCallbackFcn(app, @meanButtonValueChanged, true);
            app.meanButton.Text = 'mean';
            app.plotroiCheckBox = uicheckbox(app.UIFigure);
            app.plotroiCheckBox.Text = 'plot roi';
            app.plotroiCheckBox.Value = true;
            app.viewbkgcompButton = uibutton(app.UIFigure, 'push');
            app.viewbkgcompButton.ButtonPushedFcn = createCallbackFcn(app, @viewbkgcompButtonPushed, true);
            app.viewbkgcompButton.Text = 'view bkg comp';
            app.removemeanmeanCheckBox = uicheckbox(app.UIFigure);
            app.removemeanmeanCheckBox.Text = 'remove mean mean';
            app.removemeanmeanCheckBox.Value = true;
            app.savepathEditFieldLabel = uilabel(app.UIFigure);
            app.savepathEditFieldLabel.Text = 'save path';
            app.savepathEditField = uieditfield(app.UIFigure, 'text');
            app.save_dsetButton = uibutton(app.UIFigure, 'push');
            app.save_dsetButton.ButtonPushedFcn = createCallbackFcn(app, @save_dsetButtonPushed, true);
            app.save_dsetButton.Text = 'save_dset';
            app.cminEditFieldLabel = uilabel(app.UIFigure);
            app.cminEditFieldLabel.Text = 'cmin';
            app.cminEditField = uieditfield(app.UIFigure, 'numeric');
            app.cminEditField.ValueChangedFcn = createCallbackFcn(app, @cminEditFieldValueChanged, true);
            app.cmaxEditFieldLabel = uilabel(app.UIFigure);
            app.cmaxEditFieldLabel.Text = 'cmax';
            app.cmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.cmaxEditField.ValueChangedFcn = createCallbackFcn(app, @cmaxEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = clicktrace4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end